classdef blinker_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        DataTab                     matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        TimesincelastblinkLabel     matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        FrequencyofBuzzerLabel      matlab.ui.control.Label
        StartStopButton_2           matlab.ui.control.StateButton
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Gauge                       matlab.ui.control.Gauge
        Slider                      matlab.ui.control.Slider
        Label_3                     matlab.ui.control.Label
        CalibrationTab              matlab.ui.container.Tab
        UIAxes2                     matlab.ui.control.UIAxes
        BlinktwiceinquicksuccessionLabel  matlab.ui.control.Label
        BlinkonceasquicklyaspossibleLabel  matlab.ui.control.Label
        BlinkonceslowlyLabel        matlab.ui.control.Label
        BlinkoncenormallyLabel      matlab.ui.control.Label
        Lamp_2                      matlab.ui.control.Lamp
        StartLoggingButton          matlab.ui.control.Button
        StageDropDownLabel          matlab.ui.control.Label
        StageDropDown               matlab.ui.control.DropDown
        BlinkoncenormallyLabel_2    matlab.ui.control.Label
        BlinkoncenormallyLabel_3    matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        LoggingDurationSliderLabel  matlab.ui.control.Label
        LoggingDurationSlider       matlab.ui.control.Slider
        DothesesequentiallyLabel    matlab.ui.control.Label
        CalibrateButton             matlab.ui.control.Button
        Lamp_3                      matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        ardu=serial('COM3','Baudrate',9600);
        starttime;     
        thresh_reading_fall = 8;    %how much reading falls before i check if it is a blink
        check_duration = 35;    %how long should i check blink for
        thresh_time_btw_blinks = 400;   %sound buzzer if i havent blinked for this time *0.01s
        min_peak_prom = 30;    %how prominent is peak for it to count as a blink
        min_peak_dist = 10;    %minimum distance between peaks in checkblink
        min_peak_width = 3;    %fastest blink
        max_peak_width = 30;    %slowest blink
        cali_peak_widths;
        cali_peak_proms;
    end
    
    methods (Access = private)
        
        function [y,readings]=checkblink(app,prev_readings,blink_between,h,ax)
            readings=prev_readings;
            for i=1:app.check_duration
                if blink_between > app.thresh_time_btw_blinks
                    %fwrite(ardu,'1','uchar');
                    app.Label_3.Text='please blink!';
                else
                    app.Label_3.Text='';
                end
                blink_between=blink_between+1;
                app.Gauge.Value=blink_between;
                k=fscanf(app.ardu);
                readings(i+2)=str2double(k);
                t =  datetime('now') - app.starttime;
                % Add points to animation
                addpoints(h,datenum(t),readings(i+2))
                % Update axes
                %ax.XLim = datenum([t-seconds(15) t]);
                %datetick('x','keeplimits')
                drawnow limitrate
            end
            
            r_negated=-1*readings;
            
            [pks,locs,w,p] = findpeaks(r_negated,'MinPeakProminence',app.min_peak_prom,'MinPeakDistance',app.min_peak_dist,'MinPeakWidth',app.min_peak_width,'MaxPeakWidth',app.max_peak_width);
            
            if length(pks)==1
                y=1;
            else
                y=0;
            end

        end
        
        function k=movingavg(app, x, num_points)
            if length(x)<=num_points
                k=sum(x)/length(x);
            else
                lastelements=x(end-num_points+1:end);
                k=sum(lastelements)/num_points;
            end
        end
    end

    methods (Access = private)

        % Value changed function: StartStopButton_2
        function StartStopButton_2ValueChanged(app, event)
            value = app.StartStopButton_2.Value;
            if value == 1
                %pause(0.00001);
                cla(app.UIAxes);
                %ardu=serial('COM3','Baudrate',9600);
                fopen(app.ardu);
                k1=fscanf(app.ardu);        %throw away the first sample
                
                %initialise first 2 readings in y and first 2 values for moving average
                k2=fscanf(app.ardu);
                y(1)=str2double(k2);
                current_avg(1)=y(1);
                k3=fscanf(app.ardu);
                y(2)=str2double(k3);
                current_avg(2)=(y(2)+y(1))/2;
                
                blink_between=0; writedata='1';
                i=0;
            
                h=animatedline(app.UIAxes);
                ax=app.UIAxes;
                %ax.YLim = [0 1024];
                app.starttime = datetime('now');
                app.Lamp.Color=[0 1 0];
                while value == 1 
                    i=i+1;      %update index
                    
                    if blink_between > app.thresh_time_btw_blinks     %sound buzzer if you havent blinked for some time
                        %fwrite(ardu,writedata,'uchar');
                        app.Label_3.Text='please blink!';
                    else
                        app.Label_3.Text='';
                    end
                    
                    blink_between=blink_between+1;      %count how long you havent blinked
                    app.Gauge.Value=blink_between;
                    k=fscanf(app.ardu);     %collect data from arduino
                    y(i+1)=str2double(k);
                    
                    t =  datetime('now')-app.starttime;
                    addpoints(h,datenum(t),y(i+1))      %add points to animation
                    %ax.XLim = datenum([t-seconds(15) t]);       %update axes
                    %datetick('x','keeplimits')
                    drawnow limitrate     %consider drawnow limitrate
                    
                    if current_avg(i)-y(i+1) > app.thresh_reading_fall    %set threshold for how much reading has to have fallen before you check whether it is a blink
                        prev_readings=[y(i) y(i+1)];
                        [blink,readings]=checkblink(app,prev_readings,blink_between,h,ax);
                        y=[y readings(3:end)];      %append readings obtained during check to y vector
                        i=i+app.check_duration;     %update index
                        if blink==1
                            app.Label.Text='you blinked';
                            app.Label_2.Text=num2str(blink_between);        %display time since last blink
                            blink_between=0;        %reset time between blinks to zero
                        elseif blink==0
                            app.Label.Text='not a blink';
                            blink_between=blink_between+app.check_duration;     %if not a blink, addd the checking time to time you havent blinked
                            app.Label_2.Text=num2str(blink_between);
                        end
                    end
                    
                    current_avg(i+1)=movingavg(app,y,15);      %update moving average
                  
                end
            elseif value == 0
%                 if ~isempty(instrfind)
%                      fclose(instrfind);
%                      delete(instrfind);
%                 end
                %cla(app.UIAxes)
                fclose(app.ardu);
                delete(app.ardu);
                app.Lamp.Color=[1 0 0];
            end
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;       %this switch has been deleted
%             if strcmp(value,'On')
%                 app.Lamp.Color=[0 1 0];
%             else
%                 app.Lamp.Color=[1 0 0];
%             end
        end

        % Callback function
        function StartStopButtonValueChanged(app, event)
            value = app.StartStopButton.Value;      %this button has been deleted
%             if value == 1
%                 ardu=serial('COM3','Baudrate',9600);
%                 fopen(ardu);
%                 k1=fscanf(ardu);        %throw away the first sample
%                 
%                 i=0;
%                 app.Lamp_2.Color=[0 1 0];
%                 while value == 1 
%                     i=i+1;      %update index
%                     k=fscanf(ardu);     %collect data from arduino
%                     y(i)=str2double(k);
%                 end
%             elseif value == 0
%                 if ~isempty(instrfind)
%                      fclose(instrfind);
%                      delete(instrfind);
%                 end
%                 %cla(app.UIAxes)
%                 app.Lamp_2.Color=[1 0 0];
%             end
        end

        % Button pushed function: StartLoggingButton
        function StartLoggingButtonPushed(app, event)
            cla(app.UIAxes2);
            %ardu=serial('COM3','Baudrate',9600);
            fopen(app.ardu);
            k1=fscanf(app.ardu);        %throw away the first sample
            
            h=animatedline(app.UIAxes2);
            ax=app.UIAxes2;
            numpoints = app.LoggingDurationSlider.Value;
            ax.XLim = [0 numpoints];
            x=linspace(1,numpoints,numpoints);
            
            app.Lamp_2.Color=[0 1 0];
            for i=1:numpoints
                k=fscanf(app.ardu);     %collect data from arduino
                y(i)=str2double(k);
                addpoints(h,x(i),y(i));
                drawnow limitrate
            end
            
            y_negated = -1*y;
            
            stagevalue=app.StageDropDown.Value;
            if stagevalue == '1'
                [pks,locs,w,p] = findpeaks(y_negated);
                if length(pks) ~= 2
                    app.Label_4.Text='repeat stage 1, adjust slider if necessary';
                else
                    app.min_peak_dist=locs(2)-locs(1);
                    app.cali_peak_proms=p;
                    app.cali_peak_widths=w;
                end
            else 
                [pks,locs,w,p] = findpeaks(y_negated);
                if length(pks) ~= 1
                    app.Label_4.Text='repeat this stage, adjust slider if necessary';
                else
                    app.cali_peak_proms=[app.cali_peak_proms p];
                    app.cali_peak_widths=[app.cali_peak_widths w];
                end
            end

            fclose(app.ardu);
            delete(app.ardu);
            %clear ardu
            app.Lamp_2.Color=[1 0 0];
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            app.min_peak_prom = min(app.cali_peak_proms);    %how prominent is peak for it to count as a blink
            %app.min_peak_dist = 10;    %minimum distance between peaks in checkblink
            app.min_peak_width = min(app.cali_peak_widths);    %fastest blink
            app.max_peak_width = max(app.cali_peak_widths);    %slowest blink
            app.Lamp_3.Color=[0 1 0];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UIAxes = uiaxes(app.DataTab);
            title(app.UIAxes, 'IR Sensor Data')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'IR Sensor Reading')
            app.TimesincelastblinkLabel = uilabel(app.DataTab);
            app.TimesincelastblinkLabel.Text = 'Time since last blink:';
            app.Lamp = uilamp(app.DataTab);
            app.FrequencyofBuzzerLabel = uilabel(app.DataTab);
            app.FrequencyofBuzzerLabel.Text = 'Frequency of Buzzer:';
            app.StartStopButton_2 = uibutton(app.DataTab, 'state');
            app.StartStopButton_2.ValueChangedFcn = createCallbackFcn(app, @StartStopButton_2ValueChanged, true);
            app.StartStopButton_2.Text = 'Start/Stop';
            app.Label = uilabel(app.DataTab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.DataTab);
            app.Label_2.Text = '';
            app.Gauge = uigauge(app.DataTab, 'circular');
            app.Gauge.Limits = [0 400];
            app.Slider = uislider(app.DataTab);
            app.Slider.Limits = [100 2050];
            app.Slider.Value = 100;
            app.Label_3 = uilabel(app.DataTab);
            app.Label_3.Text = '';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.UIAxes2 = uiaxes(app.CalibrationTab);
            title(app.UIAxes2, 'IR Sensor Data')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'IR Sensor Reading')
            app.BlinktwiceinquicksuccessionLabel = uilabel(app.CalibrationTab);
            app.BlinktwiceinquicksuccessionLabel.Text = '1. Blink twice in quick succession';
            app.BlinkonceasquicklyaspossibleLabel = uilabel(app.CalibrationTab);
            app.BlinkonceasquicklyaspossibleLabel.Text = '2. Blink once as quickly as possible';
            app.BlinkonceslowlyLabel = uilabel(app.CalibrationTab);
            app.BlinkonceslowlyLabel.Text = '3. Blink once slowly';
            app.BlinkoncenormallyLabel = uilabel(app.CalibrationTab);
            app.BlinkoncenormallyLabel.Text = '4. Blink once normally';
            app.Lamp_2 = uilamp(app.CalibrationTab);
            app.StartLoggingButton = uibutton(app.CalibrationTab, 'push');
            app.StartLoggingButton.ButtonPushedFcn = createCallbackFcn(app, @StartLoggingButtonPushed, true);
            app.StartLoggingButton.Text = 'Start Logging';
            app.StageDropDownLabel = uilabel(app.CalibrationTab);
            app.StageDropDownLabel.Text = 'Stage';
            app.StageDropDown = uidropdown(app.CalibrationTab);
            app.StageDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.StageDropDown.Value = '1';
            app.BlinkoncenormallyLabel_2 = uilabel(app.CalibrationTab);
            app.BlinkoncenormallyLabel_2.Text = '5. Blink once normally';
            app.BlinkoncenormallyLabel_3 = uilabel(app.CalibrationTab);
            app.BlinkoncenormallyLabel_3.Text = '6. Blink once normally';
            app.Label_4 = uilabel(app.CalibrationTab);
            app.Label_4.Text = '';
            app.LoggingDurationSliderLabel = uilabel(app.CalibrationTab);
            app.LoggingDurationSliderLabel.Text = 'Logging Duration';
            app.LoggingDurationSlider = uislider(app.CalibrationTab);
            app.LoggingDurationSlider.Limits = [150 300];
            app.LoggingDurationSlider.Value = 150;
            app.DothesesequentiallyLabel = uilabel(app.CalibrationTab);
            app.DothesesequentiallyLabel.Text = 'Do these sequentially:';
            app.CalibrateButton = uibutton(app.CalibrationTab, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate!';
            app.Lamp_3 = uilamp(app.CalibrationTab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = blinker_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end